classdef laiiqautilities_win64 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ArchivoMenu             matlab.ui.container.Menu
        NuevoMenu               matlab.ui.container.Menu
        CinticaOzonizacinMenu   matlab.ui.container.Menu
        VisorOzonogramaMenu     matlab.ui.container.Menu
        VisorEspectroUVVisMenu  matlab.ui.container.Menu
        BiorreaccinMenu_3       matlab.ui.container.Menu
        AbrirMenu               matlab.ui.container.Menu
        OzonogramaMenu          matlab.ui.container.Menu
        EspectroUVVisMenu       matlab.ui.container.Menu
        BiorreaccinMenu         matlab.ui.container.Menu
        GuardarMenu_2           matlab.ui.container.Menu
        GuardarcomoMenu         matlab.ui.container.Menu
        SalirMenu               matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        CinticaOzonizacinTab    matlab.ui.container.Tab
        CinticaButton_7         matlab.ui.control.Button
        CrearnuevoLabel_14      matlab.ui.control.Label
        HTML3_2                 matlab.ui.control.HTML
        InstruccionesTab_2      matlab.ui.container.Tab
        HTML3_3                 matlab.ui.control.HTML
        HTML2_2                 matlab.ui.control.HTML
        AcercadeTab             matlab.ui.container.Tab
        HTML2                   matlab.ui.control.HTML
        HTML3                   matlab.ui.control.HTML
    end

    
    properties (Access = private)
        fig
        tg
        tabcount
        tab
    end
    
    methods (Access = public)
        
        function closeTab(app,src,event)
            delete(app.tg.SelectedTab)
%             if app.tabcount == 2
%                 delete(app.tg.SelectedTab)
%                 delete(app.tab(n))
%             else
%                 delete(app.tab(n));
%                 app.tabcount=app.tabcount - 1;
% %                 app.tab(n) = 0;
%             end
%             if app.tabcount <= 0
%                 app.tabcount = 1;
%             end
%             disp(app.tab)
%             length(app.tab)
%             disp(app.tabcount)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            %             left = screenWidth*0.1;
            %             bottom = screenHeight*0.1;
            width = screenWidth*0.9;
            height = screenHeight*0.85;
            left = width*0.05;
            bottom = height*0.08;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
%             app.fig = app.UIFigure;
%             app.tabcount = 1;
%             app.tab = uitab();
            %             fig = uifigure;
            %             h = uihtml(fig);
            %             h.Position = [100 100 150 100];
            %             h.HTMLSource = '<p style="font-family:sans-serif">This is <mark>marked</mark> text.</p>';
            %             app.UIFigure.WindowState = 'maximized';
            %             disp(app.UIFigure.Position)
            %             disp(app.UIFigure.WindowState)
            %             app.TabGroup.Position = [app.UIFigure.InnerPosition(1)*0.1 app.UIFigure.InnerPosition(2)*0.1 app.UIFigure.InnerPosition(3)*1.1 app.UIFigure.InnerPosition(4)*0.85];
            %             disp(app.TabGroup.Position)
            %             disp('Position: ')
            %             disp(app.UIFigure.Position)
            %             disp('Inner Position: ')
            %              disp(app.UIFigure.InnerPosition)
            %             disp('Tab Position: ')
            %             disp(app.TabGroup.Position)
            %             disp('Tab Inner Position: ')
            %             disp(app.TabGroup.InnerPosition)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ArchivoMenu = uimenu(app.UIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.NuevoMenu = uimenu(app.ArchivoMenu);
            app.NuevoMenu.Text = 'Nuevo';
            app.CinticaOzonizacinMenu = uimenu(app.NuevoMenu);
            app.CinticaOzonizacinMenu.Text = 'Cinética Ozonización';
            app.VisorOzonogramaMenu = uimenu(app.NuevoMenu);
            app.VisorOzonogramaMenu.Text = 'Visor Ozonograma';
            app.VisorEspectroUVVisMenu = uimenu(app.NuevoMenu);
            app.VisorEspectroUVVisMenu.Text = 'Visor EspectroUV-Vis';
            app.BiorreaccinMenu_3 = uimenu(app.NuevoMenu);
            app.BiorreaccinMenu_3.Text = 'Biorreacción';
            app.AbrirMenu = uimenu(app.ArchivoMenu);
            app.AbrirMenu.Text = 'Abrir';
            app.OzonogramaMenu = uimenu(app.AbrirMenu);
            app.OzonogramaMenu.Text = 'Ozonograma';
            app.EspectroUVVisMenu = uimenu(app.AbrirMenu);
            app.EspectroUVVisMenu.Text = 'EspectroUV-Vis';
            app.BiorreaccinMenu = uimenu(app.AbrirMenu);
            app.BiorreaccinMenu.Text = 'Biorreacción';
            app.GuardarMenu_2 = uimenu(app.ArchivoMenu);
            app.GuardarMenu_2.Text = 'Guardar';
            app.GuardarcomoMenu = uimenu(app.ArchivoMenu);
            app.GuardarcomoMenu.Text = 'Guardar como';
            app.SalirMenu = uimenu(app.ArchivoMenu);
            app.SalirMenu.Text = 'Salir';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CinticaOzonizacinTab = uitab(app.TabGroup);
            app.CinticaOzonizacinTab.Title = 'Cinética Ozonización';
            app.CinticaButton_7 = uibutton(app.CinticaOzonizacinTab, 'push');
            app.CinticaButton_7.Text = 'Cinética';
            app.CrearnuevoLabel_14 = uilabel(app.CinticaOzonizacinTab);
            app.CrearnuevoLabel_14.Text = 'Crear nuevo:';
            app.HTML3_2 = uihtml(app.CinticaOzonizacinTab);
            app.HTML3_2.HTMLSource = 'logo.html';
            app.InstruccionesTab_2 = uitab(app.TabGroup);
            app.InstruccionesTab_2.Title = 'Instrucciones';
            app.HTML3_3 = uihtml(app.InstruccionesTab_2);
            app.HTML3_3.HTMLSource = 'logo.html';
            app.HTML2_2 = uihtml(app.InstruccionesTab_2);
            app.HTML2_2.HTMLSource = 'instructions.html';
            app.AcercadeTab = uitab(app.TabGroup);
            app.AcercadeTab.Title = 'Acerca de';
            app.HTML2 = uihtml(app.AcercadeTab);
            app.HTML2.HTMLSource = 'about.html';
            app.HTML3 = uihtml(app.AcercadeTab);
            app.HTML3.HTMLSource = 'logo.html';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = laiiqautilities_win64

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end